classdef HW1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        lab_bl     matlab.ui.control.Label
        lab_br     matlab.ui.control.Label
        lab_tl     matlab.ui.control.Label
        lab_tr     matlab.ui.control.Label
        lab_title  matlab.ui.control.Label
        but_br     matlab.ui.control.Button
        but_bl     matlab.ui.control.Button
        but_tr     matlab.ui.control.Button
        but_tl     matlab.ui.control.Button
    end

    
    properties (Access = private)
        m_img; % Image selected to be displayed by user
        m_IconParts % cell array contains 4 slices of the image as the icon of the 4 buttons
        m_RedSeq; % the order the button turning red
        m_TurnRedIdx; % an int index recording which button is currently red
        m_HitDelay; % double array recording the time it takes for the user to hit each red button after it turns red
    end
    
    properties (Access = public)
        RedButtonPressed % flag checking if the button turned red is pressed. Must be public so the waitfor function can check its value
    end
    
    methods (Access = private)
        function m_SetButtonIcon(app)
            [fName, pName] = uigetfile({'*.png'; '*.jpeg'; '*.jpg'}, "MultiSelect", "off");
            app.m_img = imread([pName  fName]);
            [nRow, nCol, ~] = size(app.m_img);
            imgVrtBoundaryIdx = floor(nRow / 2);
            imgHozBoundaryIdx = floor(nCol / 2);
            
            app.m_IconParts{1} = app.m_img(1 : imgVrtBoundaryIdx, 1 : imgHozBoundaryIdx, :);
            app.m_IconParts{2} = app.m_img(1 :imgVrtBoundaryIdx, imgHozBoundaryIdx : end, :);
            app.m_IconParts{3} = app.m_img(imgVrtBoundaryIdx : end, 1 : imgHozBoundaryIdx, :);
            app.m_IconParts{4} = app.m_img(imgVrtBoundaryIdx : end, imgHozBoundaryIdx : end, :);
        end
        
        function m_PlayGame(app)
            buttons = {app.but_tl, app.but_tr, app.but_bl, app.but_br};
            labels = {app.lab_tl, app.lab_tr, app.lab_bl, app.lab_br};
            for hit = 1:4
                app.m_TurnRedIdx = app.m_RedSeq(hit);
                buttons{app.m_TurnRedIdx}.BackgroundColor = 'r';
                t0 = tic;
                waitfor(app, 'RedButtonPressed', true);
                app.RedButtonPressed = false;
                
                app.m_HitDelay(hit) = toc(t0);
                app.m_DisplayHitDelay(labels, hit);
                
                buttons{app.m_TurnRedIdx}.Icon = app.m_IconParts{app.m_TurnRedIdx};
                app.RedButtonPressed = false;
            end
        end

        function m_MatchButtonPressed(app, pressedIdx)
            if pressedIdx == app.m_TurnRedIdx
                app.RedButtonPressed = true;
            end
        end
        
        function m_DisplayHitDelay(app, labels, hitIdx)
            labels{hitIdx}.Text = sprintf('%.3f', app.m_HitDelay(hitIdx));
            labels{hitIdx}.Visible = 'On';
        end
        
        function m_EndGame(app)
            app.m_BlinkBackgroundRed();
            app.m_DropTitle();
            if sum(app.m_HitDelay) < 2000
                pause(4);
                close(app.UIFigure);
            end
        end
        
        function m_BlinkBackgroundRed(app)
            for i = 1:2
                app.UIFigure.Color = 'r';
                pause(0.2);
                app.UIFigure.Color = 'w';
                pause(0.2);
            end
            app.UIFigure.Color = 'r';
        end
        
        function m_DropTitle(app)
            app.lab_title.Text = sprintf("You took %.3f seconds in total!", sum(app.m_HitDelay));
            app.lab_title.Position(3) = 400;
            while app.lab_title.Position(2) > 15
                app.lab_title.Position(2) = app.lab_title.Position(2) -10;
                pause(0.01);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.m_SetButtonIcon();
            app.RedButtonPressed = false;
            app.m_RedSeq = randperm(4);
            app.m_PlayGame();
            app.m_EndGame();
        end

        % Button pushed function: but_tl
        function but_tlButtonPushed(app, event)
            buttonIdx = 1;
            app.m_MatchButtonPressed(buttonIdx);
        end

        % Button pushed function: but_tr
        function but_trButtonPushed(app, event)
            buttonIdx = 2;
            app.m_MatchButtonPressed(buttonIdx);
        end

        % Button pushed function: but_bl
        function but_blButtonPushed(app, event)
            buttonIdx = 3;
            app.m_MatchButtonPressed(buttonIdx);
        end

        % Button pushed function: but_br
        function but_brButtonPushed(app, event)
            buttonIdx = 4;
            app.m_MatchButtonPressed(buttonIdx);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.but_tl = uibutton(app.UIFigure, 'push');
            app.but_tl.ButtonPushedFcn = createCallbackFcn(app, @but_tlButtonPushed, true);
            app.but_tl.Text = '';
            app.but_tr = uibutton(app.UIFigure, 'push');
            app.but_tr.ButtonPushedFcn = createCallbackFcn(app, @but_trButtonPushed, true);
            app.but_tr.Text = '';
            app.but_bl = uibutton(app.UIFigure, 'push');
            app.but_bl.ButtonPushedFcn = createCallbackFcn(app, @but_blButtonPushed, true);
            app.but_bl.Text = '';
            app.but_br = uibutton(app.UIFigure, 'push');
            app.but_br.ButtonPushedFcn = createCallbackFcn(app, @but_brButtonPushed, true);
            app.but_br.Text = '';
            app.lab_title = uilabel(app.UIFigure);
            app.lab_title.Text = 'B10831020: Whack-a-mole';
            app.lab_tr = uilabel(app.UIFigure);
            app.lab_tr.Visible = 'off';
            app.lab_tl = uilabel(app.UIFigure);
            app.lab_tl.Visible = 'off';
            app.lab_br = uilabel(app.UIFigure);
            app.lab_br.Visible = 'off';
            app.lab_bl = uilabel(app.UIFigure);
            app.lab_bl.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end